classdef matching < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        midi_wave         matlab.ui.control.UIAxes
        midi_Slider       matlab.ui.control.Slider
        GridLayout3       matlab.ui.container.GridLayout
        opensynthButton   matlab.ui.control.Button
        openhumanButton   matlab.ui.control.Button
        rescale144Button  matlab.ui.control.Button
        addpointButton    matlab.ui.control.Button
        undoButton        matlab.ui.control.Button
        modifymidButton   matlab.ui.control.Button
        loadpointsButton  matlab.ui.control.Button
        savepointsButton  matlab.ui.control.Button
        GridLayout4       matlab.ui.container.GridLayout
        human_wave        matlab.ui.control.UIAxes
        human_Slider      matlab.ui.control.Slider
    end

    
    properties (Access = private)
        midi
        human
        synth
        fs=4410; %down-sampled
        scale=4410;
        matching_points=[];
        synth_points=[];
        human_points=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: opensynthButton
        function opensynthButtonPushed(app, event)
            file=uigetfile({'*.wav','WAV文件'},"MultiSelect","off");
            if file
                [app.synth,~]=audioread(file);
                if length(app.synth(1,:))>1
                    app.synth=app.synth(:,1);
                end
                app.synth=app.synth(1:10:end);
                plot(app.midi_wave,(1:length(app.synth))/app.fs,app.synth);
                hold(app.midi_wave,'on');
                app.midi_Slider.Limits=[1 length(app.synth)];
                app.midi_Slider.Value=1;
                app.midi_wave.XLim=(app.midi_Slider.Value+[1 app.scale])/app.fs;
                app.midi_wave.YLim=[-0.5 0.5];
            end
        end

        % Button pushed function: openhumanButton
        function openhumanButtonPushed(app, event)
            file=uigetfile({'*.wav','WAV文件'},"MultiSelect","off");
            if file
                [app.human,~]=audioread(file);
                if length(app.human(1,:))>1
                    app.human=app.human(:,1);
                end
                app.human=app.human(1:10:end);
                plot(app.human_wave,(1:length(app.human))/app.fs,app.human);
                hold(app.human_wave,'on');
                app.human_Slider.Limits=[1 length(app.human)];
                app.human_Slider.Value=1;
                app.human_wave.XLim=(app.human_Slider.Value+[1 app.scale])/app.fs;
                app.human_wave.YLim=[-0.5 0.5];
            end
        end

        % Value changed function: midi_Slider
        function midi_SliderValueChanged(app, event)
            app.midi_wave.XLim=(app.midi_Slider.Value+[1 app.scale])/app.fs;
        end

        % Value changed function: human_Slider
        function human_SliderValueChanged(app, event)
            app.human_wave.XLim=(app.human_Slider.Value+[1 app.scale])/app.fs;
        end

        % Button pushed function: rescale144Button
        function rescale144ButtonPushed(app, event)
            if app.scale<4*app.fs
                app.scale=app.scale*2;
            else
                app.scale=app.fs/4;
            end
            app.midi_SliderValueChanged('');
            app.human_SliderValueChanged('');
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    app.midi_Slider.Value=max(app.midi_Slider.Value-app.scale/10,1);
                case 'd'
                    app.midi_Slider.Value=min(app.midi_Slider.Value+app.scale/10,length(app.synth));
                case 'z'
                    app.human_Slider.Value=max(app.human_Slider.Value-app.scale/10,1);
                case 'c'
                    app.human_Slider.Value=min(app.human_Slider.Value+app.scale/10,length(app.human));
                case 'A'
                    app.midi_Slider.Value=max(app.midi_Slider.Value-app.scale/4,1);
                case 'D'
                    app.midi_Slider.Value=min(app.midi_Slider.Value+app.scale/4,length(app.synth));
                case 'Z'
                    app.human_Slider.Value=max(app.human_Slider.Value-app.scale/4,1);
                case 'C'
                    app.human_Slider.Value=min(app.human_Slider.Value+app.scale/4,length(app.human));
            end
            app.midi_SliderValueChanged('');
            app.human_SliderValueChanged('');
        end

        % Button pushed function: addpointButton
        function addpointButtonPushed(app, event)
            app.matching_points=[app.matching_points;app.midi_Slider.Value/app.fs,app.human_Slider.Value/app.fs];
            t=plot(app.midi_wave,[1,1]*app.midi_Slider.Value/app.fs,[-1 1],'r');
            app.synth_points=[app.synth_points t];
            drawnow
            t=plot(app.human_wave,[1,1]*app.human_Slider.Value/app.fs,[-1 1],'r');
            app.human_points=[app.human_points t];
            drawnow
        end

        % Button pushed function: undoButton
        function undoButtonPushed(app, event)
            if ~isempty(app.matching_points)
                app.matching_points=app.matching_points(1:end-1,:);
                delete(app.synth_points(end));
                app.synth_points=app.synth_points(1:end-1);
                delete(app.human_points(end));
                app.human_points=app.human_points(1:end-1);
            end
        end

        % Button pushed function: modifymidButton
        function modifymidButtonPushed(app, event)
            app.matching_points=[21.3663929435507	21.4622426243810;
                21.7413929435507	21.8122426243810;
                21.9913929435507	22.0372426243810;
                23.4163929435507	23.4122426243810;
                23.9663929435507	23.9872426243810;
                25.5163929435507	25.5372426243810;
                25.8163929435507	25.8372426243810;
                26.4163929435507	26.4372426243810;
                26.8163929435507	26.8372426243810;
                27.0163929435507	27.0622426243810;
                27.4663929435507	27.4622426243810;
                29.3913929435507	29.4372426243810;
                30.6913929435507	30.6622426243810;
                31.6913929435507	31.7122426243810;
                32.4663929435507	32.4622426243810;
                33.9663929435507	33.9372426243810;
                34.0913929435507	34.1122426243810;
                35.3163929435507	35.3622426243810;
                35.6163929435507	35.6622426243810;
                39.0663929435507	39.0372426243810;
                39.5663929435507	39.6122426243810;
                43.2163929435507	43.1872426243810;
                43.7163929435507	43.7122426243810;
                46.4413929435507	46.4872426243810;
                49.8413929435507	49.7872426243810;
                50.0913929435507	50.0622426243810;
                50.3913929435507	50.3872426243810];
            midifile=uigetfile({'*.mid','MIDI文件'},'MultiSelect',"off");
            if midifile
                app.midi=readmidi(midifile);
                midi_onset=app.midi(:,6);
                midi_offset=app.midi(:,7)+midi_onset;
                midi_delta=0*midi_onset;
                for i=1:length(app.matching_points(:,1))
                    [~,t]=min(abs(midi_onset-app.matching_points(i,1)));
                    midi_delta(t)=app.matching_points(i,2)-app.matching_points(i,1);
                end
                midi_onset=midi_onset+midi_delta;
                midi_offset=midi_offset+midi_delta;
                for i=1:length(midi_offset)-1
                    if midi_offset(i)>midi_onset(i+1)
                        midi_offset(i)=midi_onset(i+1);
                    end
                    if midi_offset(i)<midi_onset(i+1) && midi_offset(i)+0.2>midi_onset(i+1)
                        midi_offset(i)=midi_onset(i+1);
                    end
                end
                
                midi_output=app.midi;
                midi_output(:,6)=midi_onset;
                midi_output(:,7)=midi_offset-midi_onset;
                
                writemidi(midi_output,'output.mid');
            end
            %             disp(app.matching_points)
        end

        % Button pushed function: loadpointsButton
        function loadpointsButtonPushed(app, event)
            file=uigetfile('matching_points.mat','MultiSelect',"off");
            if file
                t=load(file);
                app.matching_points=t.t;
                for i=1:length(app.matching_points(:,1))
                    t=plot(app.midi_wave,[1,1]*app.matching_points(i,1),[-1 1],'r');
                    app.synth_points=[app.synth_points t];
                    drawnow
                    t=plot(app.human_wave,[1,1]*app.matching_points(i,2),[-1 1],'r');
                    app.human_points=[app.human_points t];
                    drawnow
                end
            end
        end

        % Button pushed function: savepointsButton
        function savepointsButtonPushed(app, event)
            t=app.matching_points;
            uisave('t','matching_points.mat');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.midi_wave = uiaxes(app.GridLayout2);
            title(app.midi_wave, {''; ''})
            xlabel(app.midi_wave, '')
            ylabel(app.midi_wave, '')
            app.midi_wave.YTick = [];
            app.midi_Slider = uislider(app.GridLayout2);
            app.midi_Slider.ValueChangedFcn = createCallbackFcn(app, @midi_SliderValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.opensynthButton = uibutton(app.GridLayout3, 'push');
            app.opensynthButton.ButtonPushedFcn = createCallbackFcn(app, @opensynthButtonPushed, true);
            app.opensynthButton.Text = 'open synth';
            app.openhumanButton = uibutton(app.GridLayout3, 'push');
            app.openhumanButton.ButtonPushedFcn = createCallbackFcn(app, @openhumanButtonPushed, true);
            app.openhumanButton.Text = 'open human';
            app.rescale144Button = uibutton(app.GridLayout3, 'push');
            app.rescale144Button.ButtonPushedFcn = createCallbackFcn(app, @rescale144ButtonPushed, true);
            app.rescale144Button.Text = {'rescale '; '(1/4~4)'};
            app.addpointButton = uibutton(app.GridLayout3, 'push');
            app.addpointButton.ButtonPushedFcn = createCallbackFcn(app, @addpointButtonPushed, true);
            app.addpointButton.Text = 'add point';
            app.undoButton = uibutton(app.GridLayout3, 'push');
            app.undoButton.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undoButton.Text = 'undo';
            app.modifymidButton = uibutton(app.GridLayout3, 'push');
            app.modifymidButton.ButtonPushedFcn = createCallbackFcn(app, @modifymidButtonPushed, true);
            app.modifymidButton.Text = 'modify mid';
            app.loadpointsButton = uibutton(app.GridLayout3, 'push');
            app.loadpointsButton.ButtonPushedFcn = createCallbackFcn(app, @loadpointsButtonPushed, true);
            app.loadpointsButton.Text = 'load points';
            app.savepointsButton = uibutton(app.GridLayout3, 'push');
            app.savepointsButton.ButtonPushedFcn = createCallbackFcn(app, @savepointsButtonPushed, true);
            app.savepointsButton.Text = 'save points';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.human_wave = uiaxes(app.GridLayout4);
            title(app.human_wave, {''; ''})
            xlabel(app.human_wave, '')
            ylabel(app.human_wave, '')
            app.human_wave.YTick = [];
            app.human_Slider = uislider(app.GridLayout4);
            app.human_Slider.ValueChangedFcn = createCallbackFcn(app, @human_SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matching

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end